classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        DropDown_9                   matlab.ui.control.DropDown
        Label_7                      matlab.ui.control.Label
        Label_6                      matlab.ui.control.Label
        Label_5                      matlab.ui.control.Label
        DropDown_8                   matlab.ui.control.DropDown
        DropDown_8Label              matlab.ui.control.Label
        DropDown_7                   matlab.ui.control.DropDown
        DropDown_7Label              matlab.ui.control.Label
        DropDown_6                   matlab.ui.control.DropDown
        DropDown_6Label              matlab.ui.control.Label
        DropDown_5                   matlab.ui.control.DropDown
        DropDown_5Label              matlab.ui.control.Label
        DropDown_4                   matlab.ui.control.DropDown
        DropDown_4Label              matlab.ui.control.Label
        DropDown_3                   matlab.ui.control.DropDown
        DropDown_3Label              matlab.ui.control.Label
        Label_4                      matlab.ui.control.Label
        DropDown_2                   matlab.ui.control.DropDown
        Label_3                      matlab.ui.control.Label
        DropDown                     matlab.ui.control.DropDown
        Label_2                      matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        Button_2                     matlab.ui.control.Button
        Button                       matlab.ui.control.Button
        RightPanel                   matlab.ui.container.Panel
        Label_9                      matlab.ui.control.Label
        NDropDown                    matlab.ui.control.DropDown
        NDropDownLabel               matlab.ui.control.Label
        TmaxDropDown                 matlab.ui.control.DropDown
        TmaxDropDownLabel            matlab.ui.control.Label
        LnearDropDown                matlab.ui.control.DropDown
        LnearDropDownLabel           matlab.ui.control.Label
        stepaSstepb25Sstepc05SLabel  matlab.ui.control.Label
        Label_8                      matlab.ui.control.Label
        betaDropDown                 matlab.ui.control.DropDown
        betaDropDownLabel            matlab.ui.control.Label
        SDropDown                    matlab.ui.control.DropDown
        SDropDownLabel               matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        status1 = zeros(1,10); % 启动参数
        h1;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function qidong(app, event)
            [map, OptimalPath, Path_size] = main(app.status1);
            imagesc(app.UIAxes,(map))
            hold(app.UIAxes,'on')
            colormap(app.UIAxes,flipud(gray))
            ytick = linspace(0.5,99.5,100);%绘制网格图
            xtick = linspace(0.5,99.5,100);
            [x,y] = meshgrid(xtick,ytick);
            plot(app.UIAxes,x,y,'k',y,x,'k')
            start = 1;
            for j = 1:app.status1(1,9)
                p1 = plot(app.UIAxes,OptimalPath(start,1),OptimalPath(start,2),'x','color',[(1/app.status1(1,9))*j,0.1*j,0.1*j]);
                p2 = plot(app.UIAxes,OptimalPath((start - 1) + Path_size(j,1),1),OptimalPath((start - 1) + Path_size(j,1),2),'^','color',[0.1*j,(1/app.status1(1,9))*j,0.1*j]);
                p3 = plot(app.UIAxes,OptimalPath(start:(start - 1) + Path_size(j,1),1),OptimalPath(start:(start - 1) + Path_size(j,1),2),'color',[0.1*j,0.1*j,(1/app.status1(1,9))*j]);
                %legend(app.UIAxes,[p1 p2 p3],{'目标','起点','路径'})
                start = Path_size(j,1) + start;
            end
            title(app.UIAxes,'由暗到亮依次为不同路径')
            hold(app.UIAxes,'off')
        end

        % Button pushed function: Button_2
        function quxiao(app, event)
            delete(app);
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            app.status1(1,1)=str2num(value);%axmax
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            value = app.DropDown_2.Value;
            app.status1(1,2)=str2num(value);%axmin
        end

        % Value changed function: DropDown_3
        function DropDown_3ValueChanged(app, event)
            value = app.DropDown_3.Value;
            app.status1(1,3)=str2num(value);%aymax
        end

        % Value changed function: DropDown_4
        function DropDown_4ValueChanged(app, event)
            value = app.DropDown_4.Value;
            app.status1(1,4)=str2num(value);%aymin
        end

        % Value changed function: DropDown_5
        function DropDown_5ValueChanged(app, event)
            value = app.DropDown_5.Value;
            app.status1(1,8)=str2num(value);%eymin
        end

        % Value changed function: DropDown_6
        function DropDown_6ValueChanged(app, event)
            value = app.DropDown_6.Value;
            app.status1(1,7)=str2num(value);%eymax
        end

        % Value changed function: DropDown_7
        function DropDown_7ValueChanged(app, event)
            value = app.DropDown_7.Value;
            app.status1(1,6)=str2num(value);%exmin
        end

        % Value changed function: DropDown_8
        function DropDown_8ValueChanged(app, event)
            value = app.DropDown_8.Value;
            app.status1(1,5)=str2num(value);%exmax
        end

        % Value changed function: DropDown_9
        function DropDown_9ValueChanged(app, event)
            value = app.DropDown_9.Value;
            app.status1(1,9)=str2num(value);%army_size
        end

        % Value changed function: NDropDown
        function NDropDownValueChanged(app, event)
            value = app.NDropDown.Value;
            app.status1(1,10)=str2num(value);% WPA_N
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {583, 583};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Button = uibutton(app.LeftPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @qidong, true);
            app.Button.Text = '启动';
            app.Button_2 = uibutton(app.LeftPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @quxiao, true);
            app.Button_2.Text = '取消';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '友军生成区域横坐标';
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = '上限';
            app.DropDown = uidropdown(app.LeftPanel);
            app.DropDown.Items = {'20', '0', '25', '30', '35'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '0';
            app.Label_3 = uilabel(app.LeftPanel);
            app.Label_3.Text = '下限';
            app.DropDown_2 = uidropdown(app.LeftPanel);
            app.DropDown_2.Items = {'5', '10', '15', '20', '0'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Value = '0';
            app.Label_4 = uilabel(app.LeftPanel);
            app.Label_4.Text = '友军生成区域纵坐标';
            app.DropDown_3Label = uilabel(app.LeftPanel);
            app.DropDown_3Label.Text = '上限';
            app.DropDown_3 = uidropdown(app.LeftPanel);
            app.DropDown_3.Items = {'20', '25', '30', '35', '0'};
            app.DropDown_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_3ValueChanged, true);
            app.DropDown_3.Value = '0';
            app.DropDown_4Label = uilabel(app.LeftPanel);
            app.DropDown_4Label.Text = '下限';
            app.DropDown_4 = uidropdown(app.LeftPanel);
            app.DropDown_4.Items = {'5', '10', '15', '20', '0'};
            app.DropDown_4.ValueChangedFcn = createCallbackFcn(app, @DropDown_4ValueChanged, true);
            app.DropDown_4.Value = '0';
            app.DropDown_5Label = uilabel(app.LeftPanel);
            app.DropDown_5Label.Text = '下限';
            app.DropDown_5 = uidropdown(app.LeftPanel);
            app.DropDown_5.Items = {'60', '65', '70', '0'};
            app.DropDown_5.ValueChangedFcn = createCallbackFcn(app, @DropDown_5ValueChanged, true);
            app.DropDown_5.Value = '0';
            app.DropDown_6Label = uilabel(app.LeftPanel);
            app.DropDown_6Label.Text = '上限';
            app.DropDown_6 = uidropdown(app.LeftPanel);
            app.DropDown_6.Items = {'70', '75', '80', '85', '0'};
            app.DropDown_6.ValueChangedFcn = createCallbackFcn(app, @DropDown_6ValueChanged, true);
            app.DropDown_6.Value = '0';
            app.DropDown_7Label = uilabel(app.LeftPanel);
            app.DropDown_7Label.Text = '下限';
            app.DropDown_7 = uidropdown(app.LeftPanel);
            app.DropDown_7.Items = {'60', '65', '70', '0'};
            app.DropDown_7.ValueChangedFcn = createCallbackFcn(app, @DropDown_7ValueChanged, true);
            app.DropDown_7.Value = '0';
            app.DropDown_8Label = uilabel(app.LeftPanel);
            app.DropDown_8Label.Text = '上限';
            app.DropDown_8 = uidropdown(app.LeftPanel);
            app.DropDown_8.Items = {'70', '75', '80', '85', '0'};
            app.DropDown_8.ValueChangedFcn = createCallbackFcn(app, @DropDown_8ValueChanged, true);
            app.DropDown_8.Value = '0';
            app.Label_5 = uilabel(app.LeftPanel);
            app.Label_5.Text = '敌军生成区域纵坐标';
            app.Label_6 = uilabel(app.LeftPanel);
            app.Label_6.Text = '敌军生成区域横坐标';
            app.Label_7 = uilabel(app.LeftPanel);
            app.Label_7.Text = '双方单位数量';
            app.DropDown_9 = uidropdown(app.LeftPanel);
            app.DropDown_9.Items = {'3', '4', '5', '0'};
            app.DropDown_9.ValueChangedFcn = createCallbackFcn(app, @DropDown_9ValueChanged, true);
            app.DropDown_9.Value = '0';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SDropDownLabel = uilabel(app.RightPanel);
            app.SDropDownLabel.Text = '步长系数 S =';
            app.SDropDown = uidropdown(app.RightPanel);
            app.SDropDown.Items = {'0.1', '0.12', '0.14'};
            app.SDropDown.Value = '0.12';
            app.betaDropDownLabel = uilabel(app.RightPanel);
            app.betaDropDownLabel.Text = '种群更新系数 beta = ';
            app.betaDropDown = uidropdown(app.RightPanel);
            app.betaDropDown.Items = {'2', '1.8', '2.2'};
            app.betaDropDown.Value = '2';
            app.Label_8 = uilabel(app.RightPanel);
            app.Label_8.Text = '狼群算法参数';
            app.stepaSstepb25Sstepc05SLabel = uilabel(app.RightPanel);
            app.stepaSstepb25Sstepc05SLabel.Text = {'步长分别为'; 'stepa = S'; 'stepb = 2.5*S'; 'stepc = 0.5*S'};
            app.LnearDropDownLabel = uilabel(app.RightPanel);
            app.LnearDropDownLabel.Text = '距离决定系数 Lnear = ';
            app.LnearDropDown = uidropdown(app.RightPanel);
            app.LnearDropDown.Items = {'0.3', '0.35', '0.4'};
            app.LnearDropDown.Value = '0.3';
            app.TmaxDropDownLabel = uilabel(app.RightPanel);
            app.TmaxDropDownLabel.Text = '游走行为次数 Tmax = ';
            app.TmaxDropDown = uidropdown(app.RightPanel);
            app.TmaxDropDown.Items = {'11', '12', '13'};
            app.TmaxDropDown.Value = '12';
            app.NDropDownLabel = uilabel(app.RightPanel);
            app.NDropDownLabel.Text = '狼群数量 N =';
            app.NDropDown = uidropdown(app.RightPanel);
            app.NDropDown.Items = {'40', '50', '60', '0'};
            app.NDropDown.ValueChangedFcn = createCallbackFcn(app, @NDropDownValueChanged, true);
            app.NDropDown.Value = '0';
            app.Label_9 = uilabel(app.RightPanel);
            app.Label_9.Text = '结果图';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end